classdef ventana_atenuacion_potencia < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AtenuacinporpotenciaUIFigure  matlab.ui.Figure
        lkmEditField                  matlab.ui.control.NumericEditField
        LkmEditFieldLabel             matlab.ui.control.Label
        adbkmEditField                matlab.ui.control.NumericEditField
        AdBkmLabel                    matlab.ui.control.Label
        adbEditField                  matlab.ui.control.NumericEditField
        AdBLabel                      matlab.ui.control.Label
        pinEditField                  matlab.ui.control.NumericEditField
        PinWEditFieldLabel            matlab.ui.control.Label
        poutEditField                 matlab.ui.control.NumericEditField
        PoutWEditFieldLabel           matlab.ui.control.Label
        variabletext_2                matlab.ui.control.Label
        CalcularButton                matlab.ui.control.Button
        variabletext                  matlab.ui.control.Label
        DropDown                      matlab.ui.control.DropDown
        logoUFPS                      matlab.ui.control.Image
        AtenuacinporPotenciaLabel     matlab.ui.control.Label
        atras                         matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: atras
        function atrasButtonPushed(app, event)
            ventana_errores()
            delete(app)
        end

        % Drop down opening function: DropDown
        function DropDownOpening(app, event)
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            
            if app.DropDown.Value=='Pout     '
               app.lkmEditField.Editable="off"
               app.adbkmEditField.Editable="off"
               app.adbEditField.Editable="on"
               app.pinEditField.Editable="on"
               app.poutEditField.Editable="off"
               app.lkmEditField.Value=0
               app.adbkmEditField.Value=0
               app.adbEditField.Value=0
               app.pinEditField.Value=0
               app.poutEditField.Value=0
            elseif app.DropDown.Value=='Pin      '
               app.lkmEditField.Editable="off"
               app.adbkmEditField.Editable="off"
               app.adbEditField.Editable="on"
               app.pinEditField.Editable="off"
               app.poutEditField.Editable="on"
               app.lkmEditField.Value=0
               app.adbkmEditField.Value=0
               app.adbEditField.Value=0
               app.pinEditField.Value=0
               app.poutEditField.Value=0
            elseif app.DropDown.Value=='A (dB)   '
               app.lkmEditField.Editable="off"
               app.adbkmEditField.Editable="off"
               app.adbEditField.Editable="off"
               app.pinEditField.Editable="on"
               app.poutEditField.Editable="on"
               app.lkmEditField.Value=0
               app.adbkmEditField.Value=0
               app.adbEditField.Value=0
               app.pinEditField.Value=0
               app.poutEditField.Value=0
            elseif app.DropDown.Value=='A (dB/km)'
               app.lkmEditField.Editable="on"
               app.adbkmEditField.Editable="off"
               app.adbEditField.Editable="off"
               app.pinEditField.Editable="on"
               app.poutEditField.Editable="on"
               app.lkmEditField.Value=0
               app.adbkmEditField.Value=0
               app.adbEditField.Value=0
               app.pinEditField.Value=0
               app.poutEditField.Value=0
            elseif app.DropDown.Value=='L        '
               app.lkmEditField.Editable="off"
               app.adbkmEditField.Editable="on"
               app.adbEditField.Editable="of"
               app.pinEditField.Editable="on"
               app.poutEditField.Editable="on"
               app.lkmEditField.Value=0
               app.adbkmEditField.Value=0
               app.adbEditField.Value=0
               app.pinEditField.Value=0
               app.poutEditField.Value=0
            else
               app.lkmEditField.Editable="off"
               app.adbkmEditField.Editable="off"
               app.adbEditField.Editable="off"
               app.pinEditField.Editable="off"
               app.poutEditField.Editable="off"
               app.lkmEditField.Value=0
               app.adbkmEditField.Value=0
               app.adbEditField.Value=0
               app.pinEditField.Value=0
               app.poutEditField.Value=0
            end
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            if app.DropDown.Value=='Pout     '
               incognita=app.pinEditField.Value*(10^((app.adbEditField.Value)/(10)));
               app.poutEditField.Value = incognita
            elseif app.DropDown.Value=='Pin      '
               incognita=app.poutEditField.Value/(10^((app.adbEditField.Value)/10));
               app.pinEditField.Value = incognita
            elseif app.DropDown.Value=='A (dB)   '
               incognita=10*log10(app.poutEditField.Value/app.pinEditField.Value);
               app.adbEditField.Value = incognita
            elseif app.DropDown.Value=='A (dB/km)'
               incognita=(10*log10(app.poutEditField.Value/app.pinEditField.Value))/app.lkmEditField.Value;
               app.adbkmEditField.Value = incognita
            elseif app.DropDown.Value=='L        '
               incognita=(10*log10(app.poutEditField.Value/app.pinEditField.Value))/app.adbkmEditField.Value;
               app.lkmEditField.Value = incognita
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AtenuacinporpotenciaUIFigure = uifigure('Visible', 'off');
            app.AtenuacinporpotenciaUIFigure.Name = 'Atenuación por potencia';
            app.AtenuacinporpotenciaUIFigure.Icon = 'Logo_test.png';
            app.atras = uibutton(app.AtenuacinporpotenciaUIFigure, 'push');
            app.atras.ButtonPushedFcn = createCallbackFcn(app, @atrasButtonPushed, true);
            app.atras.Icon = 'atras.PNG';
            app.atras.Text = '';
            app.AtenuacinporPotenciaLabel = uilabel(app.AtenuacinporpotenciaUIFigure);
            app.AtenuacinporPotenciaLabel.Text = {'Atenuación por'; 'Potencia'};
            app.logoUFPS = uiimage(app.AtenuacinporpotenciaUIFigure);
            app.logoUFPS.ImageSource = 'logo_UFPS.png';
            app.DropDown = uidropdown(app.AtenuacinporpotenciaUIFigure);
            app.DropDown.Items = {'--       ', 'Pout     ', 'Pin      ', 'A (dB)   ', 'A (dB/km)', 'L        '};
            app.DropDown.DropDownOpeningFcn = createCallbackFcn(app, @DropDownOpening, true);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '--       ';
            app.variabletext = uilabel(app.AtenuacinporpotenciaUIFigure);
            app.variabletext.Text = 'Elija la variable que desea conocer';
            app.CalcularButton = uibutton(app.AtenuacinporpotenciaUIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Icon = 'boton_calcular.jpg';
            app.CalcularButton.Text = 'Calcular';
            app.variabletext_2 = uilabel(app.AtenuacinporpotenciaUIFigure);
            app.variabletext_2.Text = 'Ingrese los datos:';
            app.PoutWEditFieldLabel = uilabel(app.AtenuacinporpotenciaUIFigure);
            app.PoutWEditFieldLabel.Text = 'Pout (µW)';
            app.poutEditField = uieditfield(app.AtenuacinporpotenciaUIFigure, 'numeric');
            app.poutEditField.Editable = 'off';
            app.PinWEditFieldLabel = uilabel(app.AtenuacinporpotenciaUIFigure);
            app.PinWEditFieldLabel.Text = 'Pin (µW)';
            app.pinEditField = uieditfield(app.AtenuacinporpotenciaUIFigure, 'numeric');
            app.pinEditField.Editable = 'off';
            app.AdBLabel = uilabel(app.AtenuacinporpotenciaUIFigure);
            app.AdBLabel.Text = 'A (dB)';
            app.adbEditField = uieditfield(app.AtenuacinporpotenciaUIFigure, 'numeric');
            app.adbEditField.Editable = 'off';
            app.AdBkmLabel = uilabel(app.AtenuacinporpotenciaUIFigure);
            app.AdBkmLabel.Text = 'A (dB/km)';
            app.adbkmEditField = uieditfield(app.AtenuacinporpotenciaUIFigure, 'numeric');
            app.adbkmEditField.Editable = 'off';
            app.LkmEditFieldLabel = uilabel(app.AtenuacinporpotenciaUIFigure);
            app.LkmEditFieldLabel.Text = 'L (km)';
            app.lkmEditField = uieditfield(app.AtenuacinporpotenciaUIFigure, 'numeric');
            app.lkmEditField.Editable = 'off';
            % Show the figure after all components are created
            app.AtenuacinporpotenciaUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ventana_atenuacion_potencia

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AtenuacinporpotenciaUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AtenuacinporpotenciaUIFigure)
        end
    end
end