classdef ventana_dispersion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DispersinUIFigure     matlab.ui.Figure
        CalcularButton        matlab.ui.control.Button
        variabletext_3        matlab.ui.control.Label
        variabletext_2        matlab.ui.control.Label
        variabletext          matlab.ui.control.Label
        EditFieldLabel_6      matlab.ui.control.Label
        EditFieldLabel_5      matlab.ui.control.Label
        LEditField            matlab.ui.control.NumericEditField
        LLabel                matlab.ui.control.Label
        DEditField            matlab.ui.control.NumericEditField
        DLabel                matlab.ui.control.Label
        EditFieldLabel_4      matlab.ui.control.Label
        EditFieldLabel_3      matlab.ui.control.Label
        EditFieldLabel_2      matlab.ui.control.Label
        lambdaDropDown        matlab.ui.control.DropDown
        dmEditField           matlab.ui.control.NumericEditField
        DmLabel               matlab.ui.control.Label
        deltalambdaEditField  matlab.ui.control.NumericEditField
        Label                 matlab.ui.control.Label
        lambdaEditField       matlab.ui.control.NumericEditField
        Label_2               matlab.ui.control.Label
        DropDown              matlab.ui.control.DropDown
        logoUFPS              matlab.ui.control.Image
        DispersinLabel        matlab.ui.control.Label
        atras                 matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            
            if app.DropDown.Value=='Ventana 1 '
               app.lambdaEditField.Editable="off"
               app.dmEditField.Editable="off"
               app.lambdaEditField.Visible="on"
               app.lambdaDropDown.Visible="off"
               app.lambdaDropDown.Editable="off"
               app.deltalambdaEditField.Editable="on"
               app.LEditField.Editable="on"
               app.lambdaEditField.Value = 850;
               app.dmEditField.Value = 84.208;
               
            elseif app.DropDown.Value=='Ventana 2 '
               app.lambdaEditField.Editable="off"
               app.dmEditField.Editable="off"
               app.lambdaEditField.Visible="on"
               app.lambdaDropDown.Visible="off"
               app.lambdaDropDown.Editable="off"
               app.deltalambdaEditField.Editable="on"
               app.LEditField.Editable="on"
               app.lambdaEditField.Value = 1350;
               app.dmEditField.Value = 6.866;
               
            elseif app.DropDown.Value=='Ventana 3 '
               app.lambdaEditField.Editable="off"
               app.dmEditField.Editable="off"
               app.lambdaEditField.Visible="on"
               app.lambdaDropDown.Visible="off"
               app.lambdaDropDown.Editable="off"
               app.deltalambdaEditField.Editable="on"
               app.LEditField.Editable="on"
               app.lambdaEditField.Value = 1550;
               app.dmEditField.Value = 21.519;
            elseif app.DropDown.Value=='Otro valor'
               app.lambdaEditField.Value = 0;
               app.dmEditField.Value = 0;
               app.lambdaEditField.Visible="off"
               app.lambdaDropDown.Visible="on"
               app.lambdaDropDown.Editable="off"
               app.deltalambdaEditField.Editable="on"
               app.LEditField.Editable="on"
               app.dmEditField.Editable="off"
            else 
               app.lambdaEditField.Editable="off"
               app.lambdaEditField.Visible="on"
               app.lambdaDropDown.Visible="off"
               app.lambdaDropDown.Editable="off"
               app.dmEditField.Editable="off"
               app.deltalambdaEditField.Editable="off"
               app.LEditField.Editable="off"
               app.dmEditField.Value = 0;
               app.lambdaEditField.Value = 0;
               app.LEditField.Value = 0;
               app.deltalambdaEditField.Value = 0;
            end
        end

        % Drop down opening function: DropDown
        function DropDownOpening(app, event)

        end

        % Value changed function: lambdaDropDown
        function lambdaDropDownValueChanged(app, event)
            value = app.lambdaDropDown.Value;
            
            if value == '700  ';
              app.dmEditField.Value = 172.902;
            elseif value == '750  ';
              app.dmEditField.Value = 135.313;
            elseif value == '800  ';
              app.dmEditField.Value = 106.609;
            elseif value == '900  ';
              app.dmEditField.Value = 66.382;
            elseif value == '950  ';
              app.dmEditField.Value = 51.944;
            elseif value == '1000 ';
              app.dmEditField.Value = 40.058;
            elseif value == '1050 ';
              app.dmEditField.Value = 30.121;
            elseif value == '1100 ';
              app.dmEditField.Value = 21.695;
            elseif value == '1150 ';
              app.dmEditField.Value = 14.451;
            elseif value == '1200 ';
              app.dmEditField.Value = 8.142;
            elseif value == '1250 ';
              app.dmEditField.Value = 2.578;
            elseif value == '1300 ';
              app.dmEditField.Value = 2.386;
            elseif value == '1400 ';
              app.dmEditField.Value = 10.954;
            elseif value == '1450 ';
              app.dmEditField.Value = 14.721;
            elseif value == '1500 ';
              app.dmEditField.Value = 18.227;
            elseif value == '1600 ';
              app.dmEditField.Value = 24.636;
            else 
              app.dmEditField.Value = 0;  
            end
        end

        % Button pushed function: atras
        function atrasButtonPushed(app, event)
            ventana_errores()
            delete(app)
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            app.DEditField.Value = app.deltalambdaEditField.Value*app.dmEditField.Value*app.LEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DispersinUIFigure = uifigure('Visible', 'off');
            app.DispersinUIFigure.Name = 'Dispersión';
            app.DispersinUIFigure.Icon = 'Logo_test.png';
            app.atras = uibutton(app.DispersinUIFigure, 'push');
            app.atras.ButtonPushedFcn = createCallbackFcn(app, @atrasButtonPushed, true);
            app.atras.Icon = 'atras.PNG';
            app.atras.Text = '';
            app.DispersinLabel = uilabel(app.DispersinUIFigure);
            app.DispersinLabel.Text = 'Dispersión';
            app.logoUFPS = uiimage(app.DispersinUIFigure);
            app.logoUFPS.ImageSource = 'logo_UFPS.png';
            app.DropDown = uidropdown(app.DispersinUIFigure);
            app.DropDown.Items = {'---       ', 'Ventana 1 ', 'Ventana 2 ', 'Ventana 3 ', 'Otro valor'};
            app.DropDown.DropDownOpeningFcn = createCallbackFcn(app, @DropDownOpening, true);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '---       ';
            app.Label_2 = uilabel(app.DispersinUIFigure);
            app.Label_2.Text = 'λ₀ ';
            app.lambdaEditField = uieditfield(app.DispersinUIFigure, 'numeric');
            app.lambdaEditField.Editable = 'off';
            app.Label = uilabel(app.DispersinUIFigure);
            app.Label.Text = 'Δλ ';
            app.deltalambdaEditField = uieditfield(app.DispersinUIFigure, 'numeric');
            app.deltalambdaEditField.Editable = 'off';
            app.DmLabel = uilabel(app.DispersinUIFigure);
            app.DmLabel.Text = 'Dm';
            app.dmEditField = uieditfield(app.DispersinUIFigure, 'numeric');
            app.dmEditField.Editable = 'off';
            app.lambdaDropDown = uidropdown(app.DispersinUIFigure);
            app.lambdaDropDown.Items = {'Elige', '700  ', '800  ', '900  ', '950  ', '1000 ', '1050 ', '1100 ', '1150 ', '1200 ', '1250 ', '1300 ', '1400 ', '1450 ', '1500 ', '1600 '};
            app.lambdaDropDown.ValueChangedFcn = createCallbackFcn(app, @lambdaDropDownValueChanged, true);
            app.lambdaDropDown.Visible = 'off';
            app.lambdaDropDown.Value = 'Elige';
            app.EditFieldLabel_2 = uilabel(app.DispersinUIFigure);
            app.EditFieldLabel_2.Text = 'nm';
            app.EditFieldLabel_3 = uilabel(app.DispersinUIFigure);
            app.EditFieldLabel_3.Text = 'ps/nm*km';
            app.EditFieldLabel_4 = uilabel(app.DispersinUIFigure);
            app.EditFieldLabel_4.Text = 'nm';
            app.DLabel = uilabel(app.DispersinUIFigure);
            app.DLabel.Text = 'D';
            app.DEditField = uieditfield(app.DispersinUIFigure, 'numeric');
            app.DEditField.Editable = 'off';
            app.LLabel = uilabel(app.DispersinUIFigure);
            app.LLabel.Text = 'L';
            app.LEditField = uieditfield(app.DispersinUIFigure, 'numeric');
            app.LEditField.Editable = 'off';
            app.EditFieldLabel_5 = uilabel(app.DispersinUIFigure);
            app.EditFieldLabel_5.Text = 'ps';
            app.EditFieldLabel_6 = uilabel(app.DispersinUIFigure);
            app.EditFieldLabel_6.Text = 'km';
            app.variabletext = uilabel(app.DispersinUIFigure);
            app.variabletext.Text = 'Elige la longitud de onda con la que desea trabajar';
            app.variabletext_2 = uilabel(app.DispersinUIFigure);
            app.variabletext_2.Text = 'Resultados';
            app.variabletext_3 = uilabel(app.DispersinUIFigure);
            app.variabletext_3.Text = 'Ingresa los datos:';
            app.CalcularButton = uibutton(app.DispersinUIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Icon = 'boton_calcular.jpg';
            app.CalcularButton.Text = 'Calcular';
            % Show the figure after all components are created
            app.DispersinUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ventana_dispersion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DispersinUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DispersinUIFigure)
        end
    end
end