classdef ventana_ley_de_snell < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FiberMLUIFigure   matlab.ui.Figure
        variabletext_3    matlab.ui.control.Label
        variabletext_2    matlab.ui.control.Label
        LeydeSnellLabel   matlab.ui.control.Label
        anguloaceptacion  matlab.ui.control.NumericEditField
        aLabel            matlab.ui.control.Label
        AN                matlab.ui.control.NumericEditField
        ANLabel           matlab.ui.control.Label
        angulocritico     matlab.ui.control.NumericEditField
        cLabel            matlab.ui.control.Label
        CalcularButton    matlab.ui.control.Button
        logoUFPS          matlab.ui.control.Image
        variabletext      matlab.ui.control.Label
        theta2EditField   matlab.ui.control.NumericEditField
        Label_2           matlab.ui.control.Label
        theta1EditField   matlab.ui.control.NumericEditField
        Label             matlab.ui.control.Label
        n2EditField       matlab.ui.control.NumericEditField
        n2EditFieldLabel  matlab.ui.control.Label
        n1EditField       matlab.ui.control.NumericEditField
        n1EditFieldLabel  matlab.ui.control.Label
        DropDown          matlab.ui.control.DropDown
        atras             matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: atras
        function atrasPushed(app, event)
            ventana_main()
            delete(app)
        end

        % Value changed function: n1EditField
        function n1EditFieldValueChanged(app, event)
            value = app.n1EditField.Value;
            
        end

        % Callback function
        function ButtonPushed(app, event)
            app.n1EditField.Editable='off';
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if app.DropDown.Value=='n1'
               app.n1EditField.Editable="off"
               app.n2EditField.Editable="on"
               app.theta1EditField.Editable="on"
               app.theta2EditField.Editable="on"
            elseif app.DropDown.Value=='n2'
               app.n1EditField.Editable="on"
               app.n2EditField.Editable="off"
               app.theta1EditField.Editable="on"
               app.theta2EditField.Editable="on"
            elseif app.DropDown.Value=='θ1'
               app.n1EditField.Editable="on"
               app.n2EditField.Editable="on"
               app.theta1EditField.Editable="off"
               app.theta2EditField.Editable="on"
            elseif app.DropDown.Value=='θ2'
               app.n1EditField.Editable="on"
               app.n2EditField.Editable="on"
               app.theta1EditField.Editable="on"
               app.theta2EditField.Editable="off"
            else
               app.n1EditField.Editable="off"
               app.n2EditField.Editable="off"
               app.theta1EditField.Editable="off"
               app.theta2EditField.Editable="off"
            end
            
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            app.GraficarButton.Enable="on"
            if app.DropDown.Value=='n1'
               incognita=app.n2EditField.Value*(sind(app.theta2EditField.Value)/sind(app.theta1EditField.Value))
               app.n1EditField.Value = incognita
               incognita=asind(app.n2EditField.Value/app.n1EditField.Value)
               app.angulocritico.Value=incognita
               incognita=sqrt((app.n1EditField.Value)^2-(app.n2EditField.Value)^2)
               app.AN.Value=incognita
               incognita=asind(app.AN.Value)
               app.anguloaceptacion.Value=incognita
            elseif app.DropDown.Value=='n2'
               incognita=app.n1EditField.Value*(sind(app.theta1EditField.Value)/sind(app.theta2EditField.Value))
               app.n2EditField.Value = incognita
               incognita=asind(app.n2EditField.Value/app.n1EditField.Value)
               app.angulocritico.Value=incognita
               incognita=sqrt((app.n1EditField.Value)^2-(app.n2EditField.Value)^2)
               app.AN.Value=incognita
               incognita=asind(app.AN.Value)
               app.anguloaceptacion.Value=incognita
            elseif app.DropDown.Value=='θ1'
               incognita=asind((app.n2EditField.Value/app.n1EditField.Value)*sind(app.theta2EditField.Value));
               app.theta1EditField.Value = incognita
               incognita=asind(app.n2EditField.Value/app.n1EditField.Value)
               app.angulocritico.Value=incognita
               incognita=sqrt((app.n1EditField.Value)^2-(app.n2EditField.Value)^2)
               app.AN.Value=incognita
               incognita=asind(app.AN.Value)
               app.anguloaceptacion.Value=incognita
            elseif app.DropDown.Value=='θ2'
               incognita=asind((app.n1EditField.Value/app.n2EditField.Value)*sind(app.theta1EditField.Value));
               app.theta2EditField.Value = incognita
               incognita=asind(app.n2EditField.Value/app.n1EditField.Value)
               app.angulocritico.Value=incognita
               incognita=sqrt((app.n1EditField.Value)^2-(app.n2EditField.Value)^2)
               app.AN.Value=incognita
               incognita=asind(app.AN.Value)
               app.anguloaceptacion.Value=incognita
            end
        end

        % Callback function
        function GraficarButtonPushed(app, event)
            %x=[app.n1EditField.Value,app.n2EditField.Value,app.theta1EditField.Value,app.theta2EditField.Value,app.angulocritico.Value,app.anguloaceptacion.Value]
            grafica();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FiberMLUIFigure = uifigure('Visible', 'off');
            app.FiberMLUIFigure.Name = 'FiberML';
            app.FiberMLUIFigure.Icon = 'Logo_test.png';
            app.atras = uibutton(app.FiberMLUIFigure, 'push');
            app.atras.ButtonPushedFcn = createCallbackFcn(app, @atrasPushed, true);
            app.atras.Icon = 'atras.PNG';
            app.atras.Text = '';
            app.DropDown = uidropdown(app.FiberMLUIFigure);
            app.DropDown.Items = {'--', 'n1', 'n2', 'θ1', 'θ2'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '--';
            app.n1EditFieldLabel = uilabel(app.FiberMLUIFigure);
            app.n1EditFieldLabel.Text = 'n1';
            app.n1EditField = uieditfield(app.FiberMLUIFigure, 'numeric');
            app.n1EditField.ValueChangedFcn = createCallbackFcn(app, @n1EditFieldValueChanged, true);
            app.n1EditField.Editable = 'off';
            app.n2EditFieldLabel = uilabel(app.FiberMLUIFigure);
            app.n2EditFieldLabel.Text = 'n2';
            app.n2EditField = uieditfield(app.FiberMLUIFigure, 'numeric');
            app.n2EditField.Editable = 'off';
            app.Label = uilabel(app.FiberMLUIFigure);
            app.Label.Text = 'θ1';
            app.theta1EditField = uieditfield(app.FiberMLUIFigure, 'numeric');
            app.theta1EditField.Editable = 'off';
            app.Label_2 = uilabel(app.FiberMLUIFigure);
            app.Label_2.Text = 'θ2';
            app.theta2EditField = uieditfield(app.FiberMLUIFigure, 'numeric');
            app.theta2EditField.Editable = 'off';
            app.variabletext = uilabel(app.FiberMLUIFigure);
            app.variabletext.Text = 'Elija la variable que desea conocer';
            app.logoUFPS = uiimage(app.FiberMLUIFigure);
            app.logoUFPS.ImageSource = 'logo_UFPS.png';
            app.CalcularButton = uibutton(app.FiberMLUIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Icon = 'boton_calcular.jpg';
            app.CalcularButton.Text = 'Calcular';
            app.cLabel = uilabel(app.FiberMLUIFigure);
            app.cLabel.Text = 'θc';
            app.angulocritico = uieditfield(app.FiberMLUIFigure, 'numeric');
            app.angulocritico.Editable = 'off';
            app.ANLabel = uilabel(app.FiberMLUIFigure);
            app.ANLabel.Text = 'AN';
            app.AN = uieditfield(app.FiberMLUIFigure, 'numeric');
            app.AN.Editable = 'off';
            app.aLabel = uilabel(app.FiberMLUIFigure);
            app.aLabel.Text = 'θa';
            app.anguloaceptacion = uieditfield(app.FiberMLUIFigure, 'numeric');
            app.anguloaceptacion.Editable = 'off';
            app.LeydeSnellLabel = uilabel(app.FiberMLUIFigure);
            app.LeydeSnellLabel.Text = 'Ley de Snell';
            app.variabletext_2 = uilabel(app.FiberMLUIFigure);
            app.variabletext_2.Text = 'Ingrese los datos:';
            app.variabletext_3 = uilabel(app.FiberMLUIFigure);
            app.variabletext_3.Text = 'Resultados:';
            % Show the figure after all components are created
            app.FiberMLUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ventana_ley_de_snell

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FiberMLUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FiberMLUIFigure)
        end
    end
end